classdef finalGUIFINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Sample                         matlab.ui.container.ButtonGroup
        Sample1Button                  matlab.ui.control.ToggleButton
        Sample4Button                  matlab.ui.control.ToggleButton
        Sample7Button                  matlab.ui.control.ToggleButton
        Sample2Button                  matlab.ui.control.ToggleButton
        Sample5Button                  matlab.ui.control.ToggleButton
        Sample8Button                  matlab.ui.control.ToggleButton
        Sample3Button                  matlab.ui.control.ToggleButton
        Sample6Button                  matlab.ui.control.ToggleButton
        Sample9Button                  matlab.ui.control.ToggleButton
        LoadButton                     matlab.ui.control.Button
        LampS1                         matlab.ui.control.Lamp
        LampS4                         matlab.ui.control.Lamp
        LampS7                         matlab.ui.control.Lamp
        LampS2                         matlab.ui.control.Lamp
        LampS8                         matlab.ui.control.Lamp
        LampS5                         matlab.ui.control.Lamp
        LampS3                         matlab.ui.control.Lamp
        LampS6                         matlab.ui.control.Lamp
        LampS9                         matlab.ui.control.Lamp
        DisplaySampleButton            matlab.ui.control.Button
        ConverttowavfileButton         matlab.ui.control.Button
        FilesavedtoMATLABfolderLabel   matlab.ui.control.Label
        Display                        matlab.ui.control.UIAxes
        EffectsButtonGroup             matlab.ui.container.ButtonGroup
        DelayButton                    matlab.ui.control.Button
        TonecontrolButton              matlab.ui.control.Button
        SpeedupButton                  matlab.ui.control.Button
        VoiceremovalButton             matlab.ui.control.Button
        SampleReversalButton           matlab.ui.control.Button
        PlayPauseButton                matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        AmplititudeEditFieldLabel      matlab.ui.control.Label
        AmplititudeEditField           matlab.ui.control.NumericEditField
        ToneDropDownLabel              matlab.ui.control.Label
        ToneDropDown                   matlab.ui.control.DropDown
        LapLamp                        matlab.ui.control.Lamp
        Lamp3                          matlab.ui.control.Lamp
        ThereisnosampleloadedLabel     matlab.ui.control.Label
        LoadedSampleStereoLabel        matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        ChopButton                     matlab.ui.control.Button
        MinutesEditField_2Label        matlab.ui.control.Label
        MinutesEditField_2             matlab.ui.control.NumericEditField
        SecondsEditFieldLabel          matlab.ui.control.Label
        SecondsEditField               matlab.ui.control.NumericEditField
        SecondsEditField_2Label        matlab.ui.control.Label
        SecondsEditField_2             matlab.ui.control.NumericEditField
        MinutesEditFieldLabel          matlab.ui.control.Label
        MinutesEditField               matlab.ui.control.NumericEditField
        EndTimeLabel                   matlab.ui.control.Label
        StartTimeLabel                 matlab.ui.control.Label
        SelctthestartandendtimesLabel  matlab.ui.control.Label
        ofthemusicyouwanttokeepLabel   matlab.ui.control.Label
        TheButterfingersAudioEditorLabel  matlab.ui.control.Label
        LoadedSampleMonoLabel          matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        Sampleselection
        Editsamplew
        Editsamples
        Editsamplet
        Editaudio
        aar
        sr
        time
        aar2
        sr2
        time2
        aar3
        sr3
        time3
        aar4
        sr4
        time4
        aar5
        sr5
        time5
        aar6
        sr6
        time6
        aar7
        sr7
        time7
        aar8
        sr8
        time8
        aar9
        sr9
        time9
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Sampleselection = 1
            app.Editsamplew = 0
            app.Editsamples = 0
            app.Editsamplet = 0
            app.aar = 0
            app.sr = 0
            app.time = 0
            app.aar2 = 0
            app.sr2 = 0
            app.time2 = 0
            app.aar3 = 0
            app.sr3 = 0
            app.time3 = 0
            app.aar4 = 0
            app.sr4 = 0
            app.time4 = 0
            app.aar5 = 0
            app.sr5 = 0
            app.time5 = 0
            app.aar6 = 0
            app.sr6 = 0
            app.time6 = 0
            app.aar7 = 0
            app.sr7 = 0
            app.time7 = 0
            app.aar8 = 0
            app.sr8 = 0
            app.time8 = 0
            app.aar9 = 0
            app.sr9 = 0
            app.time9 = 0
        end

        % Selection changed function: Sample
        function SampleSelectionChanged(app, event)
            selectedButton = app.Sample.SelectedObject;
            if app.Sample1Button == selectedButton
                app.Sampleselection = 1;
            elseif app.Sample2Button == selectedButton
                app.Sampleselection = 2;
            elseif app.Sample3Button == selectedButton
                app.Sampleselection = 3;
            elseif app.Sample4Button == selectedButton
                app.Sampleselection = 4;
            elseif app.Sample5Button == selectedButton
                app.Sampleselection = 5;
            elseif app.Sample6Button == selectedButton
                app.Sampleselection = 6;
            elseif app.Sample7Button == selectedButton
                app.Sampleselection = 7;
            elseif app.Sample8Button == selectedButton
                app.Sampleselection = 8;
            elseif app.Sample9Button == selectedButton
                app.Sampleselection = 9;
            end

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            bpress = 1;
            if bpress == 1
                [audiosam,loc] = uigetfile('*.*');
                locall = strcat(loc,audiosam);
                switch app.Sampleselection
                    case 1
                        [app.aar,app.sr] = audioread(locall);
                        app.time = linspace(0,length(app.aar)/app.sr,length(app.aar));
                        light = size(app.aar);
                        if light(:,2) == 2
                            app.LampS1.Color = [0 0.4470 0.7410];
                        else
                            app.LampS1.Color = [0.8500 0.3250 0.0980];
                        end
                    case 2 
                        [app.aar2,app.sr2] = audioread(locall);
                        app.time2 = linspace(0,length(app.aar2)/app.sr2,length(app.aar2));
                        light = size(app.aar2);
                        if light(:,2) == 2
                            app.LampS2.Color = [0 0.4470 0.7410];
                        else
                            app.LampS2.Color = [0.8500 0.3250 0.0980];
                        end
                    case 3
                        [app.aar3,app.sr3] = audioread(locall);
                        app.time3 = linspace(0,length(app.aar3)/app.sr3,length(app.aar3));
                        light = size(app.aar3);
                        if light(:,2) == 2
                            app.LampS3.Color = [0 0.4470 0.7410];
                        else
                            app.LampS3.Color = [0.8500 0.3250 0.0980];
                        end
                    case 4 
                        [app.aar4,app.sr4] = audioread(locall);
                        app.time4 = linspace(0,length(app.aar4)/app.sr4,length(app.aar4));
                        light = size(app.aar4);
                        if light(:,2) == 2
                            app.LampS4.Color = [0 0.4470 0.7410];
                        else
                            app.LampS4.Color = [0.8500 0.3250 0.0980];
                        end
                    case 5 
                        [app.aar5,app.sr5] = audioread(locall);
                        app.time5 = linspace(0,length(app.aar5)/app.sr5,length(app.aar5));
                        light = size(app.aar5);
                        if light(:,2) == 2
                            app.LampS5.Color = [0 0.4470 0.7410];
                        else
                            app.LampS5.Color = [0.8500 0.3250 0.0980];
                        end
                    case 6 
                        [app.aar6,app.sr6] = audioread(locall);
                        app.time6 = linspace(0,length(app.aar6)/app.sr6,length(app.aar6));
                        light = size(app.aar6);
                        if light(:,2) == 2
                            app.LampS6.Color = [0 0.4470 0.7410];
                        else
                            app.LampS6.Color = [0.8500 0.3250 0.0980];
                        end
                    case 7 
                        [app.aar7,app.sr7] = audioread(locall);
                        app.time7 = linspace(0,length(app.aar7)/app.sr7,length(app.aar7));
                        light = size(app.aar7);
                        if light(:,2) == 2
                            app.LampS7.Color = [0 0.4470 0.7410];
                        else
                            app.LampS7.Color = [0.8500 0.3250 0.0980];
                        end
                    case 8 
                        [app.aar8,app.sr8] = audioread(locall);
                        app.time8 = linspace(0,length(app.aar8)/app.sr8,length(app.aar8));
                        light = size(app.aar8);
                        if light(:,2) == 2
                            app.LampS8.Color = [0 0.4470 0.7410];
                        else
                            app.LampS8.Color = [0.8500 0.3250 0.0980];
                        end
                    case 9
                        [app.aar9,app.sr9] = audioread(locall);
                        app.time9 = linspace(0,length(app.aar9)/app.sr9,length(app.aar9));
                        light = size(app.aar9);
                        if light(:,2) == 2
                            app.LampS9.Color = [0 0.4470 0.7410];
                        else
                            app.LampS9.Color = [0.8500 0.3250 0.0980];
                        end
                    otherwise
                        y = 'You have encountered and error'
                end
            end
        end

        % Button pushed function: DisplaySampleButton
        function DisplaySampleButtonPushed(app, event)
            dpress = 1;
            if dpress == 1
                switch app.Sampleselection 
                        case 1
                            app.Editsamplew = app.aar;
                            app.Editsamples = app.sr;
                            app.Editsamplet = app.time;
                            plot(app.Display,app.Editsamplet,app.Editsamplew)
                        case 2
                            app.Editsamplew = app.aar2;
                            app.Editsamples = app.sr2;
                            app.Editsamplet = app.time2;
                            plot(app.Display,app.Editsamplet,app.Editsamplew)
                        case 3
                            app.Editsamplew = app.aar3;
                            app.Editsamples = app.sr3;
                            app.Editsamplet = app.time3;
                            plot(app.Display,app.Editsamplet,app.Editsamplew)
                        case 4
                            app.Editsamplew = app.aar4;
                            app.Editsamples = app.sr4;
                            app.Editsamplet = app.time4;
                            plot(app.Display,app.Editsamplet,app.Editsamplew)
                        case 5
                            app.Editsamplew = app.aar5;
                            app.Editsamples = app.sr5;
                            app.Editsamplet = app.time5;
                            plot(app.Display,app.Editsamplet,app.Editsamplew)
                        case 6
                            app.Editsamplew = app.aar6;
                            app.Editsamples = app.sr6;
                            app.Editsamplet = app.time6;
                            plot(app.Display,app.Editsamplet,app.Editsamplew)
                        case 7
                            app.Editsamplew = app.aar7;
                            app.Editsamples = app.sr7;
                            app.Editsamplet = app.time7;
                            plot(app.Display,app.Editsamplet,app.Editsamplew)
                        case 8
                            app.Editsamplew = app.aar8;
                            app.Editsamples = app.sr8;
                            app.Editsamplet = app.time8;
                            plot(app.Display,app.Editsamplet,app.Editsamplew)
                        case 9
                            app.Editsamplew = app.aar9;
                            app.Editsamples = app.sr9;
                            app.Editsamplet = app.time9;
                            plot(app.Display,app.Editsamplet,app.Editsamplew)
                        otherwise 
                            y = 'You have encountered an error'
                end
            dpress = 0;
            if logical(app.Editsamplew) == 0
                app.Editsamplew = 80
                app.Editsamples = 44100
            end
            app.Editaudio = audioplayer(app.Editsamplew,app.Editsamples);
            end
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonPushed2(app, event)
            if isplaying(app.Editaudio) == 0
                resume(app.Editaudio)
            elseif isplaying(app.Editaudio) == 1
                pause(app.Editaudio)
            end
                
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.Editaudio)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            spress = 1
            app.Sampleselection
            if spress == 1
                switch app.Sampleselection
                    case 1
                        app.aar = app.Editsamplew
                        app.sr = app.Editsamples
                        app.time = app.Editsamplet
                        light = size(app.aar)
                        if light(:,2) == 2
                            app.LampS1.Color = [0 0.4470 0.7410];
                        else
                            app.LampS1.Color = [0.8500 0.3250 0.0980]
                        end
                    case 2 
                        app.aar2 = app.Editsamplew
                        app.sr2 = app.Editsamples
                        app.time2 = app.Editsamplet
                        light = size(app.aar2)
                        if light(:,2) == 2
                            app.LampS2.Color = [0 0.4470 0.7410];
                        else
                            app.LampS2.Color = [0.8500 0.3250 0.0980]
                        end
                    case 3
                        app.aar3 = app.Editsamplew
                        app.sr3 = app.Editsamples
                        app.time3 = app.Editsamplet
                        light = size(app.aar3)
                        if light(:,2) == 2
                            app.LampS3.Color = [0 0.4470 0.7410];
                        else
                            app.LampS3.Color = [0.8500 0.3250 0.0980]
                        end
                    case 4 
                        app.aar4 = app.Editsamplew
                        app.sr4 = app.Editsamples
                        app.time4 = app.Editsamplet
                        light = size(app.aar4)
                        if light(:,2) == 2
                            app.LampS4.Color = [0 0.4470 0.7410];
                        else
                            app.LampS4.Color = [0.8500 0.3250 0.0980]
                        end
                    case 5 
                        app.aar5 = app.Editsamplew
                        app.sr5 = app.Editsamples
                        app.time5 = app.Editsamplet
                        light = size(app.aar5)
                        if light(:,2) == 2
                            app.LampS5.Color = [0 0.4470 0.7410];
                        else
                            app.LampS5.Color = [0.8500 0.3250 0.0980]
                        end
                    case 6 
                        app.aar6 = app.Editsamplew
                        app.sr6 = app.Editsamples
                        app.time6 = app.Editsamplet
                        light = size(app.aar6)
                        if light(:,2) == 2
                            app.LampS6.Color = [0 0.4470 0.7410];
                        else
                            app.LampS6.Color = [0.8500 0.3250 0.0980]
                        end
                    case 7 
                        app.aar7 = app.Editsamplew
                        app.sr7 = app.Editsamples
                        app.time7 = app.Editsamplet
                        light = size(app.aar7)
                        if light(:,2) == 2
                            app.LampS7.Color = [0 0.4470 0.7410];
                        else
                            app.LampS7.Color = [0.8500 0.3250 0.0980]
                        end
                    case 8 
                        app.aar8 = app.Editsamplew
                        app.sr8 = app.Editsamples
                        app.time8 = app.Editsamplet
                        light = size(app.aar8)
                        if light(:,2) == 2
                            app.LampS8.Color = [0 0.4470 0.7410];
                        else
                            app.LampS8.Color = [0.8500 0.3250 0.0980]
                        end
                    case 9
                        app.aar9 = app.Editsamplew
                        app.sr9 = app.Editsamples
                        app.time9 = app.Editsamplet
                        light = size(app.aar9)
                        if light(:,2) == 2
                            app.LampS9.Color = [0 0.4470 0.7410];
                        else
                            app.LampS9.Color = [0.8500 0.3250 0.0980]
                        end
                end
            end
        end

        % Button pushed function: DelayButton
        function DelayButtonPushed(app, event)
            N = 2 * app.Editsamples;
            app.Editsamplew(N+1:end,:) = app.Editsamplew(N+1:end,:)+app.Editsamplew(1:end-N,:);
            plot(app.Display,app.Editsamplet,app.Editsamplew)
            app.Editaudio = audioplayer(app.Editsamplew,app.Editsamples)
        end

        % Button pushed function: SpeedupButton
        function SpeedupButtonPushed(app, event)
            app.Editsamples = app.Editsamples*2;
            plot(app.Display,app.Editsamplet,app.Editsamplew)
            app.Editaudio = audioplayer(app.Editsamplew,app.Editsamples)
        end

        % Button pushed function: VoiceremovalButton
        function VoiceremovalButtonPushed(app, event)
            app.Editsamplew(:,1) = app.Editsamplew(:,1) - app.Editsamplew(:,2);
            app.Editsamplew(:,2) = app.Editsamplew(:,2) - app.Editsamplew(:,1);
            plot(app.Display,app.Editsamplet,app.Editsamplew)
            app.Editaudio = audioplayer(app.Editsamplew,app.Editsamples)
        end

        % Button pushed function: TonecontrolButton
        function TonecontrolButtonPushed(app, event)
            N = length(app.Editsamplew);
            bin_Vals = [0:N-1];
            fax_Hz = bin_Vals*app.Editsamples/N;
            N_2 = ceil(N/2);
            Hz = fax_Hz(1:N_2);
            complex = fft(app.Editsamplew);
            x = [55  77  110 156 220 311 440 622 880 1200 1800 2500 3500 5000 7000 10000 14000 20000];
            y = [-20 -20 -20 -20 -20   0   0   0   0   0    0    0    20   20   20    20     20     20];
            cs = pchip(x,y);
            modHz = ppval(cs,Hz)';
            modHz = 10.^(modHz./20);
            filter = modHz .* 0.75 + modHz .* 0.25 * j;
            filter = [filter;flipud(filter)];
            filter = ifft(filter);
            realFilter = real(filter);
            actualFilter = fft(realFilter);
            actualFilter = [actualFilter  actualFilter];
            modComplex = actualFilter.*complex;
            modPoints = ifft(modComplex);
            app.Editsamplew = modPoints;
            plot(app.Display,app.Editsamplet,app.Editsamplew)
            app.Editaudio = audioplayer(app.Editsamplew,app.Editsamples)
        end

        % Button pushed function: SampleReversalButton
        function SampleReversalButtonPushed(app, event)
            app.Editsamplew = flipud(app.Editsamplew);
            plot(app.Display,app.Editsamplet,app.Editsamplew)
            app.Editaudio = audioplayer(app.Editsamplew,app.Editsamples)
        end

        % Button pushed function: ChopButton
        function ChopButtonPushed(app, event)
            startTime = app.MinutesEditField.Value*60 + app.SecondsEditField.Value;
            endTime = app.MinutesEditField_2.Value*60 + app.SecondsEditField_2.Value;
            startIndex = app.Editsamples * startTime;
            endIndex = app.Editsamples * endTime;
            app.Editsamplew = app.Editsamplew(startIndex:endIndex,:);
            app.Editsamplet = [1:length(app.Editsamplew)];
            plot(app.Display,app.Editsamplet,app.Editsamplew)
            app.Editaudio = audioplayer(app.Editsamplew,app.Editsamples)
        end

        % Button pushed function: ConverttowavfileButton
        function ConverttowavfileButtonPushed(app, event)
            ct = 1;
            if logical(app.Editsamplew) == 0
                app.Editsamplew = 80
                app.Editsamples = 44100
                i = '.wav'
                s = strcat('emptysample',i)
                audiowrite(s,app.Editsamplew,app.Editsamples)
            elseif ct == 1
                switch app.Sampleselection 
                        case 1
                            i = '1.wav'
                            s = strcat('sample',i)
                           audiowrite(s,app.Editsamplew,app.Editsamples)
                        case 2
                            i = '2.wav'
                            s = strcat('sample',i)
                           audiowrite(s,app.Editsamplew,app.Editsamples)
                        case 3
                            i = '3.wav'
                            s = strcat('sample',i)
                           audiowrite(s,app.Editsamplew,app.Editsamples)
                        case 4
                            i = '4.wav'
                            s = strcat('sample',i)
                           audiowrite(s,app.Editsamplew,app.Editsamples)
                        case 5
                            i = '5.wav'
                            s = strcat('sample',i)
                           audiowrite(s,app.Editsamplew,app.Editsamples)
                        case 6
                            i = '6.wav'
                            s = strcat('sample',i)
                           audiowrite(s,app.Editsamplew,app.Editsamples)
                        case 7
                            i = '7.wav'
                            s = strcat('sample',i)
                           audiowrite(s,app.Editsamplew,app.Editsamples)
                        case 8
                           i = '8.wav'
                            s = strcat('sample',i)
                           audiowrite(s,app.Editsamplew,app.Editsamples)
                        case 9
                            i = '9.wav'
                            s = strcat('sample',i)
                           audiowrite(s,app.Editsamplew,app.Editsamples)
                        otherwise 
                            y = 'You have encountered an error'
                end
            ct = 0;
            end
                
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            
            [y,fs] = audioread(filename);

            fs2= value;
            audiowrite(filename,y,fs2)

            [y,fs2] = audioread(filename);
            sound(y,fs2);
        end

        % Value changed function: AmplititudeEditField
        function AmplititudeEditFieldValueChanged(app, event)
            value = app.AmplititudeEditField.Value;
            [y,fs] = audioread(filename);
            index = 1:length(y);

            Fc = 5; % frequency of trem
            A = .5; % amplitude of trem
            w = 0 % phase of trem

            trem=(w*pi/180 + A * sin(2*pi*index*(Fc/Fs)))'; 

            y = y .* trem;

            soundsc(y,Fs)
        end

        % Value changed function: ToneDropDown
        function ToneDropDownValueChanged(app, event)
            %{value = app.ToneDropDown.Value;
       
           %}
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Sample = uibuttongroup(app.UIFigure);
            app.Sample.SelectionChangedFcn = createCallbackFcn(app, @SampleSelectionChanged, true);
            app.Sample.Title = 'Sample';
            app.Sample1Button = uitogglebutton(app.Sample);
            app.Sample1Button.Text = 'Sample1';
            app.Sample1Button.Value = true;
            app.Sample4Button = uitogglebutton(app.Sample);
            app.Sample4Button.Text = 'Sample4';
            app.Sample7Button = uitogglebutton(app.Sample);
            app.Sample7Button.Text = 'Sample7';
            app.Sample2Button = uitogglebutton(app.Sample);
            app.Sample2Button.Text = 'Sample2 ';
            app.Sample5Button = uitogglebutton(app.Sample);
            app.Sample5Button.Text = 'Sample5';
            app.Sample8Button = uitogglebutton(app.Sample);
            app.Sample8Button.Text = 'Sample8';
            app.Sample3Button = uitogglebutton(app.Sample);
            app.Sample3Button.Text = 'Sample3';
            app.Sample6Button = uitogglebutton(app.Sample);
            app.Sample6Button.Text = 'Sample6';
            app.Sample9Button = uitogglebutton(app.Sample);
            app.Sample9Button.Text = 'Sample9';
            app.LoadButton = uibutton(app.Sample, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LampS1 = uilamp(app.Sample);
            app.LampS4 = uilamp(app.Sample);
            app.LampS7 = uilamp(app.Sample);
            app.LampS2 = uilamp(app.Sample);
            app.LampS8 = uilamp(app.Sample);
            app.LampS5 = uilamp(app.Sample);
            app.LampS3 = uilamp(app.Sample);
            app.LampS6 = uilamp(app.Sample);
            app.LampS9 = uilamp(app.Sample);
            app.DisplaySampleButton = uibutton(app.Sample, 'push');
            app.DisplaySampleButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySampleButtonPushed, true);
            app.DisplaySampleButton.Text = {'Display Sample'; ''};
            app.ConverttowavfileButton = uibutton(app.Sample, 'push');
            app.ConverttowavfileButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttowavfileButtonPushed, true);
            app.ConverttowavfileButton.Text = 'Convert to .wav file*';
            app.FilesavedtoMATLABfolderLabel = uilabel(app.Sample);
            app.FilesavedtoMATLABfolderLabel.Text = '* = File saved to MATLAB folder';
            app.Display = uiaxes(app.UIFigure);
            title(app.Display, 'Sample Editor')
            xlabel(app.Display, 'Time(s)')
            app.Display.YLim = [-1 1];
            app.Display.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.EffectsButtonGroup = uibuttongroup(app.UIFigure);
            app.EffectsButtonGroup.Title = 'Effects';
            app.DelayButton = uibutton(app.EffectsButtonGroup, 'push');
            app.DelayButton.ButtonPushedFcn = createCallbackFcn(app, @DelayButtonPushed, true);
            app.DelayButton.Text = 'Delay';
            app.TonecontrolButton = uibutton(app.EffectsButtonGroup, 'push');
            app.TonecontrolButton.ButtonPushedFcn = createCallbackFcn(app, @TonecontrolButtonPushed, true);
            app.TonecontrolButton.Text = 'Tone control';
            app.SpeedupButton = uibutton(app.EffectsButtonGroup, 'push');
            app.SpeedupButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedupButtonPushed, true);
            app.SpeedupButton.Text = 'Speed up';
            app.VoiceremovalButton = uibutton(app.EffectsButtonGroup, 'push');
            app.VoiceremovalButton.ButtonPushedFcn = createCallbackFcn(app, @VoiceremovalButtonPushed, true);
            app.VoiceremovalButton.Text = 'Voice removal';
            app.SampleReversalButton = uibutton(app.EffectsButtonGroup, 'push');
            app.SampleReversalButton.ButtonPushedFcn = createCallbackFcn(app, @SampleReversalButtonPushed, true);
            app.SampleReversalButton.Text = 'Sample Reversal';
            app.PlayPauseButton = uibutton(app.UIFigure, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonPushed2, true);
            app.PlayPauseButton.Text = 'Play/Pause';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = {'Save to sample'; ''};
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 123;
            app.AmplititudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplititudeEditFieldLabel.Text = 'Amplititude';
            app.AmplititudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplititudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplititudeEditFieldValueChanged, true);
            app.ToneDropDownLabel = uilabel(app.UIFigure);
            app.ToneDropDownLabel.Text = 'Tone';
            app.ToneDropDown = uidropdown(app.UIFigure);
            app.ToneDropDown.Items = {'Sine wave', 'triangle wave', 'amplitude modulation'};
            app.ToneDropDown.ValueChangedFcn = createCallbackFcn(app, @ToneDropDownValueChanged, true);
            app.ToneDropDown.Value = 'Sine wave';
            app.LapLamp = uilamp(app.UIFigure);
            app.Lamp3 = uilamp(app.UIFigure);
            app.ThereisnosampleloadedLabel = uilabel(app.UIFigure);
            app.ThereisnosampleloadedLabel.Text = 'There is no sample loaded = ';
            app.LoadedSampleStereoLabel = uilabel(app.UIFigure);
            app.LoadedSampleStereoLabel.Text = {'Loaded Sample (Stereo) ='; ''};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ChopButton = uibutton(app.Panel, 'push');
            app.ChopButton.ButtonPushedFcn = createCallbackFcn(app, @ChopButtonPushed, true);
            app.ChopButton.Text = 'Chop';
            app.MinutesEditField_2Label = uilabel(app.Panel);
            app.MinutesEditField_2Label.Text = 'Minutes';
            app.MinutesEditField_2 = uieditfield(app.Panel, 'numeric');
            app.SecondsEditFieldLabel = uilabel(app.Panel);
            app.SecondsEditFieldLabel.Text = 'Seconds';
            app.SecondsEditField = uieditfield(app.Panel, 'numeric');
            app.SecondsEditField_2Label = uilabel(app.Panel);
            app.SecondsEditField_2Label.Text = 'Seconds';
            app.SecondsEditField_2 = uieditfield(app.Panel, 'numeric');
            app.MinutesEditFieldLabel = uilabel(app.Panel);
            app.MinutesEditFieldLabel.Text = 'Minutes';
            app.MinutesEditField = uieditfield(app.Panel, 'numeric');
            app.EndTimeLabel = uilabel(app.Panel);
            app.EndTimeLabel.Text = 'End Time:';
            app.StartTimeLabel = uilabel(app.Panel);
            app.StartTimeLabel.Text = 'Start Time:';
            app.SelctthestartandendtimesLabel = uilabel(app.Panel);
            app.SelctthestartandendtimesLabel.Text = 'Selct the start and end times ';
            app.ofthemusicyouwanttokeepLabel = uilabel(app.Panel);
            app.ofthemusicyouwanttokeepLabel.Text = 'of the music you want to keep';
            app.TheButterfingersAudioEditorLabel = uilabel(app.UIFigure);
            app.TheButterfingersAudioEditorLabel.Text = 'The Butterfingers Audio Editor';
            app.LoadedSampleMonoLabel = uilabel(app.UIFigure);
            app.LoadedSampleMonoLabel.Text = 'Loaded Sample (Mono) = ';
            app.Lamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = finalGUIFINAL

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end